classdef FIRdesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FIRFilterDesginToolUIFigure   matlab.ui.Figure
        FilterOrderSpinnerLabel       matlab.ui.control.Label
        FilterOrderSpinner            matlab.ui.control.Spinner
        WindowTypeDropDownLabel       matlab.ui.control.Label
        WindowTypeDropDown            matlab.ui.control.DropDown
        NormalizedCutOffFrequancy0to1EditFieldLabel  matlab.ui.control.Label
        NormalizedCutOffFrequancy0to1EditField  matlab.ui.control.NumericEditField
        DesignButton                  matlab.ui.control.Button
        MagResponseUIAxes             matlab.ui.control.UIAxes
        BetaEditFieldLabel            matlab.ui.control.Label
        BetaEditField                 matlab.ui.control.NumericEditField
        SidelobeattenuationindBEditFieldLabel  matlab.ui.control.Label
        SidelobeattenuationindBEditField  matlab.ui.control.NumericEditField
        ResponseTypeDropDownLabel     matlab.ui.control.Label
        ResponseTypeDropDown          matlab.ui.control.DropDown
        PassBandweightEditFieldLabel  matlab.ui.control.Label
        PassBandweightEditField       matlab.ui.control.NumericEditField
        StopBandweightEditFieldLabel  matlab.ui.control.Label
        StopBandweightEditField       matlab.ui.control.NumericEditField
        DesignmethodDropDownLabel     matlab.ui.control.Label
        DesignmethodDropDown          matlab.ui.control.DropDown
        ProducedfilterisaFIRfilterofTypeILabel  matlab.ui.control.Label
        PlotOptionsDropDownLabel      matlab.ui.control.Label
        PlotOptionsDropDown           matlab.ui.control.DropDown
        sLabel                        matlab.ui.control.Label
        ShowFilterCoefficientsButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
    kernel %filter coeff
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            order = app.FilterOrderSpinner.Value; %%only even orders are allowed because the tool desgines (linear phase) FIR filters of type 1
            nOfTaps = order+1;
            
            %%%%%%%%%%%%% design the low pass version of the filter
            
            %cut off frequancy
            if (app.ResponseTypeDropDown.Value =="Low Pass")
                normalizedCutOffFreq = app.NormalizedCutOffFrequancy0to1EditField.Value; %%from 0 to 1
            elseif (app.ResponseTypeDropDown.Value =="Band Pass")
                normalizedCutOffFreq = 0.5 - app.NormalizedCutOffFrequancy0to1EditField.Value; 
            elseif(app.ResponseTypeDropDown.Value == "High Pass")
                normalizedCutOffFreq = 1 - app.NormalizedCutOffFrequancy0to1EditField.Value; %%from 0 to 1
            end
           
            %%%% windowed sic low pass kernel
            
            %creating the unwindowed finite sinc kernel
            i = 0:1:order;
            sincKernel = sinc(normalizedCutOffFreq*(i-(order/2))); %low pass kernel
            
            %%windowing
            if (app.WindowTypeDropDown.Value == "Rectangular")
                windowedSinc = sincKernel;
            elseif (app.WindowTypeDropDown.Value == "Blackman")
                windowedSinc = sincKernel .*blackman(nOfTaps)'; %checked
            elseif (app.WindowTypeDropDown.Value == "Chebyshev")
                attenuationIndB = app.SidelobeattenuationindBEditField.Value;
                windowedSinc = sincKernel .* chebwin(nOfTaps,attenuationIndB)'; %checked
            elseif (app.WindowTypeDropDown.Value == "Kaiser")
                beta = app.BetaEditField.Value;
                windowedSinc = sincKernel .* kaiser(nOfTaps,beta)'; %checked
            end
            
            
            %%% least square low pass kernal
            
            N = nOfTaps;
            k = 5*N;   %any arbitarty odd number but here it is five*N to ensure that k is larger than N and L
            L = (N-1)/2;
            
            %construction of matrix F
            Lvector = (0:1:L);
            wVector = pi*linspace(0,1,k);
            temp = wVector'*Lvector;
            F = 2*cos(temp);
            F(:,1) = F(:,1)/2;
            
            % Hd desired coeffs
            Hd = zeros(1,k);
            Hd(wVector<(pi*normalizedCutOffFreq)) = 1;
            % constructing the weights matrix
            weights = zeros(1,k);
            weights(wVector<pi*normalizedCutOffFreq) = app.PassBandweightEditField.Value;
            weights(wVector>=pi*normalizedCutOffFreq) = app.StopBandweightEditField.Value;
            W = diag(weights);
            
            %filter coeffections
            hoptimal= ((F')*(W*F))\(F'*W*Hd');
            hredandunt = flip(hoptimal(2:end));
            hoptimal = [ hredandunt' hoptimal' ]';

            
             if (app.DesignmethodDropDown.Value == "Windowed Sinc")
                app.kernel = windowedSinc;
            elseif (app.DesignmethodDropDown.Value == "Least-Squares")
                app.kernel = hoptimal';
             end
             
            %convert low pass to high or band pass filter
            if (app.ResponseTypeDropDown.Value =="Band Pass")
                app.kernel = app.kernel.*cos(pi*i/2); 
            elseif(app.ResponseTypeDropDown.Value == "High Pass")
                app.kernel = app.kernel.*cos(pi*i);
            end
            
            %%%frequancy response
            
            % 1024 is chosen randomly for better visualization
            kernelFreqMag = abs(fft(app.kernel,1024));
            % scaling for unity DC gain
            
            FreqScale = kernelFreqMag(1);
            
%             if (app.ResponseTypeDropDown.Value =="Low Pass")
%                 FreqScale = kernelFreqMag(1);
%             elseif (app.ResponseTypeDropDown.Value =="Band Pass")
%                 FreqScale = kernelFreqMag(1024/2);
%             elseif(app.ResponseTypeDropDown.Value == "High Pass")
%                 FreqScale = kernelFreqMag(1024);
%             end
            scaledKernelFreqMag = kernelFreqMag./FreqScale;


            %%%plotting
            
%             if (app.DesignmethodDropDown.Value == "Windowed Sinc")
%                 freqMag = scaledWindSincFreqMag;
%             elseif (app.DesignmethodDropDown.Value == "Least-Squares")
%                 freqMag = scaledLSfreqMag;
%             end
            
            %indices
            freqMagIndeces = linspace(0, 2, 1024);
            if (app.PlotOptionsDropDown.Value == "Linear")
                %linear plot
                plot(app.MagResponseUIAxes,freqMagIndeces(1:1024/2), scaledKernelFreqMag(1:1024/2));
            elseif (app.PlotOptionsDropDown.Value == "Log")
                %log plot
                logFreqMag = mag2db(scaledKernelFreqMag);
                plot(app.MagResponseUIAxes,freqMagIndeces(1:1024/2), logFreqMag(1:1024/2));
            end
            
            %% enable filter coeffecionts button
            app.ShowFilterCoefficientsButton.Enable = 'on';
            
          
        end

        % Value changed function: WindowTypeDropDown
        function WindowTypeDropDownValueChanged(app, event)
            value = app.WindowTypeDropDown.Value;
            if (value == "Rectangular")
                app.BetaEditField.Visible = 'off';
                app.BetaEditFieldLabel.Visible = 'off';
                app.SidelobeattenuationindBEditFieldLabel.Visible ='off';
                app.SidelobeattenuationindBEditField.Visible = 'off';
            elseif (value == "Blackman")
                app.BetaEditField.Visible = 'off';
                app.BetaEditFieldLabel.Visible = 'off';
                app.SidelobeattenuationindBEditFieldLabel.Visible ='off';
                app.SidelobeattenuationindBEditField.Visible = 'off';
            elseif (value == "Chebyshev")
                app.BetaEditField.Visible = 'off';
                app.BetaEditFieldLabel.Visible = 'off';
                app.SidelobeattenuationindBEditFieldLabel.Visible ='on';
                app.SidelobeattenuationindBEditField.Visible = 'on';
            elseif (value == "Kaiser")
                app.BetaEditField.Visible = 'on';
                app.BetaEditFieldLabel.Visible = 'on';
                app.SidelobeattenuationindBEditFieldLabel.Visible ='off';
                app.SidelobeattenuationindBEditField.Visible = 'off';
            end
        end

        % Callback function
        function UnitsDropDownValueChanged(app, event)
           
        end

        % Value changed function: DesignmethodDropDown
        function DesignmethodDropDownValueChanged(app, event)
            value = app.DesignmethodDropDown.Value;
            if (value == "Windowed Sinc")
                app.WindowTypeDropDown.Visible = 'on';
                app.WindowTypeDropDownLabel.Visible = 'on';
                app.WindowTypeDropDown.Value = "Rectangular";
                app.PassBandweightEditField.Visible = 'off';
                app.PassBandweightEditFieldLabel.Visible = 'off';
                app.StopBandweightEditField.Visible ='off';
                app.StopBandweightEditFieldLabel.Visible = 'off';
            elseif (value == "Least-Squares")
                app.WindowTypeDropDown.Visible = 'off';
                app.WindowTypeDropDownLabel.Visible = 'off';
                app.WindowTypeDropDown.Value = "Rectangular";
                app.PassBandweightEditField.Visible = 'on';
                app.PassBandweightEditFieldLabel.Visible = 'on';
                app.StopBandweightEditField.Visible ='on';
                app.StopBandweightEditFieldLabel.Visible = 'on';
                app.BetaEditField.Visible = 'off';
                app.BetaEditFieldLabel.Visible = 'off';
                app.SidelobeattenuationindBEditFieldLabel.Visible ='off';
                app.SidelobeattenuationindBEditField.Visible = 'off';
            end
        end

        % Value changed function: ResponseTypeDropDown
        function ResponseTypeDropDownValueChanged(app, event)
            value = app.ResponseTypeDropDown.Value;
            if (value == "Low Pass" || value == "High Pass")
                app.NormalizedCutOffFrequancy0to1EditFieldLabel.Text = "Normalized Cut Off Frequancy (0 to 1)";
                app.NormalizedCutOffFrequancy0to1EditField.Limits = [0 1];
                app.sLabel.Visible = 'off';
            elseif (value == "Band Pass")
                app.NormalizedCutOffFrequancy0to1EditFieldLabel.Text = "First Normalized Cut Off Frequancy (from 0 to 0.5)";
                app.NormalizedCutOffFrequancy0to1EditField.Limits = [0 0.5];
                secondCuttOff =num2str( 0.5 +  app.NormalizedCutOffFrequancy0to1EditField.Value);
                app.sLabel.Text =strcat("Second Normalized Cut off Frequancy = ",secondCuttOff,", Symmetric around fs/4");  
                app.sLabel.Visible = 'on';
            end
        end

        % Button pushed function: ShowFilterCoefficientsButton
        function ShowFilterCoefficientsButtonPushed(app, event)
filterCoeff(app.kernel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FIRFilterDesginToolUIFigure = uifigure('Visible', 'off');
            app.FIRFilterDesginToolUIFigure.Name = 'FIR Filter Desgin Tool';
            app.FilterOrderSpinnerLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.FilterOrderSpinnerLabel.Text = 'Filter Order';
            app.FilterOrderSpinner = uispinner(app.FIRFilterDesginToolUIFigure);
            app.FilterOrderSpinner.Step = 2;
            app.FilterOrderSpinner.UpperLimitInclusive = 'off';
            app.FilterOrderSpinner.Limits = [0 Inf];
            app.FilterOrderSpinner.Value = 24;
            app.WindowTypeDropDownLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.FIRFilterDesginToolUIFigure);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Blackman', 'Chebyshev', 'Kaiser'};
            app.WindowTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowTypeDropDownValueChanged, true);
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.NormalizedCutOffFrequancy0to1EditFieldLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.NormalizedCutOffFrequancy0to1EditFieldLabel.Text = 'Normalized Cut Off Frequancy (0 to 1)';
            app.NormalizedCutOffFrequancy0to1EditField = uieditfield(app.FIRFilterDesginToolUIFigure, 'numeric');
            app.NormalizedCutOffFrequancy0to1EditField.Limits = [0 1];
            app.NormalizedCutOffFrequancy0to1EditField.Value = 0.25;
            app.DesignButton = uibutton(app.FIRFilterDesginToolUIFigure, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = 'Design ';
            app.MagResponseUIAxes = uiaxes(app.FIRFilterDesginToolUIFigure);
            title(app.MagResponseUIAxes, 'Magnitude Response')
            xlabel(app.MagResponseUIAxes, {'Normalized Frequancy (*\pi rad/sample)'; ''})
            ylabel(app.MagResponseUIAxes, 'Magnitude')
            app.BetaEditFieldLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.BetaEditFieldLabel.Visible = 'off';
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.FIRFilterDesginToolUIFigure, 'numeric');
            app.BetaEditField.Visible = 'off';
            app.SidelobeattenuationindBEditFieldLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.SidelobeattenuationindBEditFieldLabel.Visible = 'off';
            app.SidelobeattenuationindBEditFieldLabel.Text = 'Sidelobe attenuation in dB';
            app.SidelobeattenuationindBEditField = uieditfield(app.FIRFilterDesginToolUIFigure, 'numeric');
            app.SidelobeattenuationindBEditField.Visible = 'off';
            app.ResponseTypeDropDownLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.ResponseTypeDropDownLabel.Text = 'Response Type';
            app.ResponseTypeDropDown = uidropdown(app.FIRFilterDesginToolUIFigure);
            app.ResponseTypeDropDown.Items = {'Low Pass', 'Band Pass', 'High Pass'};
            app.ResponseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ResponseTypeDropDownValueChanged, true);
            app.ResponseTypeDropDown.Value = 'Low Pass';
            app.PassBandweightEditFieldLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.PassBandweightEditFieldLabel.Visible = 'off';
            app.PassBandweightEditFieldLabel.Text = 'Pass Band weight';
            app.PassBandweightEditField = uieditfield(app.FIRFilterDesginToolUIFigure, 'numeric');
            app.PassBandweightEditField.Visible = 'off';
            app.PassBandweightEditField.Value = 1;
            app.StopBandweightEditFieldLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.StopBandweightEditFieldLabel.Visible = 'off';
            app.StopBandweightEditFieldLabel.Text = 'Stop Band weight';
            app.StopBandweightEditField = uieditfield(app.FIRFilterDesginToolUIFigure, 'numeric');
            app.StopBandweightEditField.Visible = 'off';
            app.StopBandweightEditField.Value = 1;
            app.DesignmethodDropDownLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.DesignmethodDropDownLabel.Text = 'Design method';
            app.DesignmethodDropDown = uidropdown(app.FIRFilterDesginToolUIFigure);
            app.DesignmethodDropDown.Items = {'Windowed Sinc', 'Least-Squares'};
            app.DesignmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DesignmethodDropDownValueChanged, true);
            app.DesignmethodDropDown.Value = 'Windowed Sinc';
            app.ProducedfilterisaFIRfilterofTypeILabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.ProducedfilterisaFIRfilterofTypeILabel.Text = {'Produced filter is a '; 'FIR filter of Type I'};
            app.PlotOptionsDropDownLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.PlotOptionsDropDownLabel.Text = 'Plot Options';
            app.PlotOptionsDropDown = uidropdown(app.FIRFilterDesginToolUIFigure);
            app.PlotOptionsDropDown.Items = {'Linear', 'Log'};
            app.PlotOptionsDropDown.Value = 'Linear';
            app.sLabel = uilabel(app.FIRFilterDesginToolUIFigure);
            app.sLabel.Visible = 'off';
            app.sLabel.Text = 's';
            app.ShowFilterCoefficientsButton = uibutton(app.FIRFilterDesginToolUIFigure, 'push');
            app.ShowFilterCoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFilterCoefficientsButtonPushed, true);
            app.ShowFilterCoefficientsButton.Enable = 'off';
            app.ShowFilterCoefficientsButton.Text = 'Show Filter Coefficients';
            % Show the figure after all components are created
            app.FIRFilterDesginToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FIRdesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FIRFilterDesginToolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FIRFilterDesginToolUIFigure)
        end
    end
end